classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResultsPanel                   matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        SimulacaocomDatasetPanel       matlab.ui.container.Panel
        ResultsPanel_2                 matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        RedeneuronalPanel              matlab.ui.container.Panel
        EditField                      matlab.ui.control.EditField
        NomedaredeLabel                matlab.ui.control.Label
        SimularcomDatasetButton        matlab.ui.control.Button
        InputsPanel                    matlab.ui.container.Panel
        DropDown_2                     matlab.ui.control.DropDown
        TreinodeRedeSimplesPanel       matlab.ui.container.Panel
        ResultsPanel_3                 matlab.ui.container.Panel
        Label_3                        matlab.ui.control.Label
        TreinarRedeSimplesButton       matlab.ui.control.Button
        CamadasEditField_2             matlab.ui.control.NumericEditField
        CamadasEditField_2Label        matlab.ui.control.Label
        NeuroniosEditField_2           matlab.ui.control.NumericEditField
        NeurniosEditFieldLabel_2       matlab.ui.control.Label
        SelecionarButton               matlab.ui.control.Button
        DropDown                       matlab.ui.control.DropDown
        CriaocaodaRedeNeuronalPanel    matlab.ui.container.Panel
        TreinarRedeButton              matlab.ui.control.Button
        ConfiguracaodaRedePanel        matlab.ui.container.Panel
        FuncaodeAtivacao2DropDown      matlab.ui.control.DropDown
        FunesdeAtivaoLabel             matlab.ui.control.Label
        TestEditField                  matlab.ui.control.NumericEditField
        TestEditFieldLabel             matlab.ui.control.Label
        ValEditField                   matlab.ui.control.NumericEditField
        ValEditFieldLabel              matlab.ui.control.Label
        TrainEditField                 matlab.ui.control.NumericEditField
        TrainEditFieldLabel            matlab.ui.control.Label
        NeuroniosEditField             matlab.ui.control.NumericEditField
        NeurniosEditFieldLabel         matlab.ui.control.Label
        CamadasEditField               matlab.ui.control.NumericEditField
        CamadasEditFieldLabel          matlab.ui.control.Label
        FuncaodeAtivacao1DropDown      matlab.ui.control.DropDown
        FunesdeAtivaoLabel_2           matlab.ui.control.Label
        RaciosdeSegmentacaoDropDown    matlab.ui.control.DropDown
        RciosdeSegmentaoDropDownLabel  matlab.ui.control.Label
        FuncaodeTreinoDropDown         matlab.ui.control.DropDown
        FunodeTreinoDropDownLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TreinarRedeButton
        function TreinarRedeButtonPushed(app, event)
            textLabel = sprintf("Accuracy de treino = %.2f", feedForwardNet_b_app(app.NeuroniosEditField.Value,app.FuncaodeAtivacao1DropDown.Value,app.FuncaodeAtivacao2DropDown.Value,app.FuncaodeTreinoDropDown.Value,app.RaciosdeSegmentacaoDropDown.Value,app.TrainEditField.Value,app.ValEditField.Value,app.TestEditField.Value));
            app.Label.Text = textLabel;
        end

        % Button pushed function: SimularcomDatasetButton
        function SimularcomDatasetButtonPushed(app, event)
            textLabel = sprintf("Accuracy de treino = %.2f", feedForwardNet_c_app(app.EditField.Value, app.DropDown_2.Value));
            app.Label_2.Text = textLabel;
        end

        % Button pushed function: TreinarRedeSimplesButton
        function TreinarRedeSimplesButtonPushed(app, event)
            stats = feedForwardNet_a_app(app.DropDown.Value, app.NeuroniosEditField_2.Value);
            textLabel = sprintf('Total Accuracy: %.2f%%\nError: %.6f\nExecution Time: %.2f segundos', ...
                stats.TotalAccuracy, stats.Error, stats.ExecutionTime);
            app.Label_3.Text = textLabel;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CriaocaodaRedeNeuronalPanel = uipanel(app.UIFigure);
            app.CriaocaodaRedeNeuronalPanel.Title = 'Treino de Rede Neuronal';
            app.ConfiguracaodaRedePanel = uipanel(app.CriaocaodaRedeNeuronalPanel);
            app.ConfiguracaodaRedePanel.Title = 'Configuraçao da Rede';
            app.FunodeTreinoDropDownLabel = uilabel(app.ConfiguracaodaRedePanel);
            app.FunodeTreinoDropDownLabel.Text = {'Função de Treino'; ''};
            app.FuncaodeTreinoDropDown = uidropdown(app.ConfiguracaodaRedePanel);
            app.FuncaodeTreinoDropDown.Items = {'trainlm', 'trainbfg', 'trainrp', 'trainscg', 'traincgb', 'traincgf', 'traincgp', 'trainoss', 'traingdx'};
            app.FuncaodeTreinoDropDown.Value = 'trainlm';
            app.RciosdeSegmentaoDropDownLabel = uilabel(app.ConfiguracaodaRedePanel);
            app.RciosdeSegmentaoDropDownLabel.Text = 'Rácios de Segmentação';
            app.RaciosdeSegmentacaoDropDown = uidropdown(app.ConfiguracaodaRedePanel);
            app.RaciosdeSegmentacaoDropDown.Items = {'dividerand', 'divideblock', 'divideint', 'divideind'};
            app.RaciosdeSegmentacaoDropDown.Value = 'dividerand';
            app.FunesdeAtivaoLabel_2 = uilabel(app.ConfiguracaodaRedePanel);
            app.FunesdeAtivaoLabel_2.Text = '1-Funções de Ativação';
            app.FuncaodeAtivacao1DropDown = uidropdown(app.ConfiguracaodaRedePanel);
            app.FuncaodeAtivacao1DropDown.Items = {'tansig', 'logsing', 'tansig', 'purelin'};
            app.FuncaodeAtivacao1DropDown.Value = 'tansig';
            app.CamadasEditFieldLabel = uilabel(app.ConfiguracaodaRedePanel);
            app.CamadasEditFieldLabel.Text = 'Camadas';
            app.CamadasEditField = uieditfield(app.ConfiguracaodaRedePanel, 'numeric');
            app.NeurniosEditFieldLabel = uilabel(app.ConfiguracaodaRedePanel);
            app.NeurniosEditFieldLabel.Text = 'Neurónios';
            app.NeuroniosEditField = uieditfield(app.ConfiguracaodaRedePanel, 'numeric');
            app.TrainEditFieldLabel = uilabel(app.ConfiguracaodaRedePanel);
            app.TrainEditFieldLabel.Text = 'Train';
            app.TrainEditField = uieditfield(app.ConfiguracaodaRedePanel, 'numeric');
            app.ValEditFieldLabel = uilabel(app.ConfiguracaodaRedePanel);
            app.ValEditFieldLabel.Text = 'Val';
            app.ValEditField = uieditfield(app.ConfiguracaodaRedePanel, 'numeric');
            app.TestEditFieldLabel = uilabel(app.ConfiguracaodaRedePanel);
            app.TestEditFieldLabel.Text = 'Test';
            app.TestEditField = uieditfield(app.ConfiguracaodaRedePanel, 'numeric');
            app.FunesdeAtivaoLabel = uilabel(app.ConfiguracaodaRedePanel);
            app.FunesdeAtivaoLabel.Text = '2-Funções de Ativação';
            app.FuncaodeAtivacao2DropDown = uidropdown(app.ConfiguracaodaRedePanel);
            app.FuncaodeAtivacao2DropDown.Items = {'tansig', 'logsing', 'tansig', 'purelin'};
            app.FuncaodeAtivacao2DropDown.Value = 'tansig';
            app.TreinarRedeButton = uibutton(app.CriaocaodaRedeNeuronalPanel, 'push');
            app.TreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarRedeButtonPushed, true);
            app.TreinarRedeButton.Text = 'Treinar Rede';
            app.TreinodeRedeSimplesPanel = uipanel(app.UIFigure);
            app.TreinodeRedeSimplesPanel.Title = 'Treino de Rede Simples';
            app.DropDown = uidropdown(app.TreinodeRedeSimplesPanel);
            app.DropDown.Items = {'Start', 'Test'};
            app.DropDown.Value = 'Start';
            app.SelecionarButton = uibutton(app.TreinodeRedeSimplesPanel, 'push');
            app.SelecionarButton.Text = 'Selecionar';
            app.NeurniosEditFieldLabel_2 = uilabel(app.TreinodeRedeSimplesPanel);
            app.NeurniosEditFieldLabel_2.Text = 'Neurónios';
            app.NeuroniosEditField_2 = uieditfield(app.TreinodeRedeSimplesPanel, 'numeric');
            app.CamadasEditField_2Label = uilabel(app.TreinodeRedeSimplesPanel);
            app.CamadasEditField_2Label.Text = 'Camadas';
            app.CamadasEditField_2 = uieditfield(app.TreinodeRedeSimplesPanel, 'numeric');
            app.TreinarRedeSimplesButton = uibutton(app.TreinodeRedeSimplesPanel, 'push');
            app.TreinarRedeSimplesButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarRedeSimplesButtonPushed, true);
            app.TreinarRedeSimplesButton.Text = 'Treinar Rede Simples';
            app.ResultsPanel_3 = uipanel(app.TreinodeRedeSimplesPanel);
            app.ResultsPanel_3.Title = 'Results';
            app.Label_3 = uilabel(app.ResultsPanel_3);
            app.Label_3.Text = '';
            app.SimulacaocomDatasetPanel = uipanel(app.UIFigure);
            app.SimulacaocomDatasetPanel.Title = 'Simulaçao com Dataset';
            app.InputsPanel = uipanel(app.SimulacaocomDatasetPanel);
            app.InputsPanel.Title = 'Inputs';
            app.DropDown_2 = uidropdown(app.InputsPanel);
            app.DropDown_2.Items = {'Start', 'Train', 'Test'};
            app.DropDown_2.Value = 'Start';
            app.SimularcomDatasetButton = uibutton(app.SimulacaocomDatasetPanel, 'push');
            app.SimularcomDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SimularcomDatasetButtonPushed, true);
            app.SimularcomDatasetButton.Text = 'Simular com Dataset';
            app.RedeneuronalPanel = uipanel(app.SimulacaocomDatasetPanel);
            app.RedeneuronalPanel.Title = 'Rede neuronal';
            app.NomedaredeLabel = uilabel(app.RedeneuronalPanel);
            app.NomedaredeLabel.Text = 'Nome da rede';
            app.EditField = uieditfield(app.RedeneuronalPanel, 'text');
            app.ResultsPanel_2 = uipanel(app.SimulacaocomDatasetPanel);
            app.ResultsPanel_2.Title = 'Results';
            app.Label_2 = uilabel(app.ResultsPanel_2);
            app.Label_2.Text = '';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.Label = uilabel(app.ResultsPanel);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end